classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TransmitterEditField           matlab.ui.control.EditField
        PlayPlotButton                 matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        AudiofileLabel                 matlab.ui.control.Label
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        CutofffrequencyEditFieldLabel  matlab.ui.control.Label
        CutofffrequencyEditField       matlab.ui.control.NumericEditField
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        GraphplotDropDownLabel         matlab.ui.control.Label
        GraphplotDropDown              matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        signal_t % Description
        fs % Description
        fvec % Description
        t % Description
        signal_f % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
   [file,path] = uigetfile({'*.wav';'*.mp3'});
if isequal(file,0)
   warndlg('Please choose a correct file');
else
    input_file = fullfile(path,file);
   app.TransmitterEditField.Value = input_file;
   
   gain = app.GainEditField.Value;
   [app.signal_t, app.fs] = audioread(input_file);

app.t = linspace(0,length(app.signal_t)/app.fs,length(app.signal_t));
app.signal_t = gain*app.signal_t; 
app.signal_f= fftshift(fft(app.signal_t/app.fs));
app.fvec = linspace(-app.fs/2,app.fs/2,length(app.signal_f));

end
        end

        % Button pushed function: PlayPlotButton
        function PlayPlotButtonPushed(app, event)

             
            if app.DropDown.Value == "FM"
                if app.GraphplotDropDown.Value == "Original Signal"
                    plot(app.UIAxes2,app.fvec,abs(app.signal_f)); 
plot(app.UIAxes3,app.fvec,angle(app.signal_f));xlim(app.UIAxes3,[-15 15]);
plot(app.UIAxes,app.t,app.signal_t); 
                    
                elseif app.GraphplotDropDown == "Filtered Signal"
                    
                    elseif app.GraphplotDropDown == "NBFM Signal"
                        
                        elseif app.GraphplotDropDown == "Demodulated Signal"


                end
            elseif app.DropDown.Value == "DSB"
                
               elseif app.DropDown.Value == "SSB"
                   
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {389, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TransmitterEditField = uieditfield(app.LeftPanel, 'text');
            app.TransmitterEditField.Editable = 'off';
            app.PlayPlotButton = uibutton(app.LeftPanel, 'push');
            app.PlayPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPlotButtonPushed, true);
            app.PlayPlotButton.Text = 'Play/Plot';
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AudiofileLabel = uilabel(app.LeftPanel);
            app.AudiofileLabel.Text = 'Audio file';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'DSB', 'SSB', 'FM'};
            app.DropDown.Value = 'FM';
            app.CutofffrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.CutofffrequencyEditFieldLabel.Text = 'Cutoff frequency';
            app.CutofffrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CutofffrequencyEditField.Value = 4000;
            app.GainEditFieldLabel = uilabel(app.LeftPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GainEditField.Value = 10;
            app.GraphplotDropDownLabel = uilabel(app.LeftPanel);
            app.GraphplotDropDownLabel.Text = 'Graph plot';
            app.GraphplotDropDown = uidropdown(app.LeftPanel);
            app.GraphplotDropDown.Items = {'Original Signal', 'Filtered Signal', 'NBFM Signal', 'Demodulated Signal'};
            app.GraphplotDropDown.Value = 'Original Signal';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Magnitude Spectrum')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Phase Spectrum')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end