classdef appmath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LinearregressionTab             matlab.ui.container.Tab
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        labelLabel                      matlab.ui.control.Label
        SrEditFieldLabel                matlab.ui.control.Label
        SrEditField                     matlab.ui.control.NumericEditField
        StEditFieldLabel                matlab.ui.control.Label
        StEditField                     matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        r2EditFieldLabel                matlab.ui.control.Label
        r2EditField                     matlab.ui.control.NumericEditField
        powermodelTab                   matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        NumberofPointsEditField_2Label  matlab.ui.control.Label
        NumberofPointsEditField_2       matlab.ui.control.NumericEditField
        CalculateButton_2               matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        yLabel_2                        matlab.ui.control.Label
        labelLabel_2                    matlab.ui.control.Label
        SrEditField_2Label              matlab.ui.control.Label
        SrEditField_2                   matlab.ui.control.NumericEditField
        StEditField_2Label              matlab.ui.control.Label
        StEditField_2                   matlab.ui.control.NumericEditField
        rEditField_2Label               matlab.ui.control.Label
        rEditField_2                    matlab.ui.control.NumericEditField
        r2EditField_2Label              matlab.ui.control.Label
        r2EditField_2                   matlab.ui.control.NumericEditField
        jacobiTab                       matlab.ui.container.Tab
        X10EditFieldLabel               matlab.ui.control.Label
        X10EditField                    matlab.ui.control.NumericEditField
        X20EditFieldLabel               matlab.ui.control.Label
        X20EditField                    matlab.ui.control.NumericEditField
        X30EditFieldLabel               matlab.ui.control.Label
        X30EditField                    matlab.ui.control.NumericEditField
        UITable_3                       matlab.ui.control.Table
        CalculateButton_3               matlab.ui.control.Button
        EntertheequationscoeffandtheirresultLabel  matlab.ui.control.Label
        X1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X3EditFieldLabel                matlab.ui.control.Label
        X3EditField                     matlab.ui.control.NumericEditField
        noofiterationsLabel             matlab.ui.control.Label
        noofiterationsEditField         matlab.ui.control.NumericEditField
        HeunsTab                        matlab.ui.container.Tab
        EquationEditFieldLabel          matlab.ui.control.Label
        EquationEditField               matlab.ui.control.EditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        X0EditFieldLabel                matlab.ui.control.Label
        X0EditField                     matlab.ui.control.NumericEditField
        Y0EditFieldLabel                matlab.ui.control.Label
        Y0EditField                     matlab.ui.control.NumericEditField
        pointofoperationatxEditFieldLabel  matlab.ui.control.Label
        pointofoperationatxEditField    matlab.ui.control.NumericEditField
        CalculateButton_4               matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
    end

    
    properties (Access = private)
        n_linear % Description
        n_pow % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = zeros(3,4);
             app.UITable_3.Data = t;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            x = [];
y = [];
           x = app.UITable.Data(:,1);
           x = x';
           y = app.UITable.Data(:,2);
           y = y';
plot(app.UIAxes,x,y,'o');
hold( app.UIAxes, 'on' )


x_sqr= x .^2;
x_y= x .*y;
x_sum= sum(x);
y_sum= sum(y);
xy_sum = sum(x_y);
xsqr_sum = sum(x_sqr);

A = [app.n_linear x_sum ; x_sum xsqr_sum];
b = [y_sum; xy_sum];
ans = inv(A) * b;

z = ans(1) + ans(2) .* x;


plot(app.UIAxes,z);

y_avg = y_sum / app.n_linear; 
St = sum ((y - y_avg).^2);

e = y - ans(1) - ans(2) .*x;
Sr = sum(e.^2);

%Coefficient of determination
rsqr = (St - Sr)/St;

% correlation coefficient
r = rsqr .^0.5;

app.StEditField.Value = St;
app.SrEditField.Value = Sr;
app.r2EditField.Value = rsqr;
app.rEditField.Value = r;
app.labelLabel.Text =ans(1)  +"+"+ ans(2) + "* x";
        end

        % Value changed function: NumberofPointsEditField
        function NumberofPointsEditFieldValueChanged(app, event)
                 app.NumberofPointsEditField.Enable = 'off';
             app.n_linear = app.NumberofPointsEditField.Value;
             t = zeros(app.n_linear,2);
             app.UITable.Data = t;
        end

        % Value changed function: NumberofPointsEditField_2
        function NumberofPointsEditField_2ValueChanged(app, event)
                     app.NumberofPointsEditField_2.Enable = 'off';
             app.n_pow = app.NumberofPointsEditField_2.Value;
             t = zeros(app.n_pow,2);
             app.UITable_2.Data = t;
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
                x=[];
X=[];
y=[];
Y=[]; 
            x = app.UITable_2.Data(:,1);
           x = x';
           X = log10(x);
           y = app.UITable_2.Data(:,2);
           y = y';
           Y = log10(y);
           plot(app.UIAxes_2,x,y,'o');
hold( app.UIAxes_2, 'on' )


X_sqr= X .^2;
X_Y= X .*Y;
X_sum= sum(X);
Y_sum= sum(Y);
XY_sum = sum(X_Y);
Xsqr_sum = sum(X_sqr);

A = [app.n_pow X_sum ; X_sum Xsqr_sum];
b = [Y_sum; XY_sum];
ans = inv(A) * b;
a = 10 .^ ans(1);
b = ans(2);

z = a.*(x.^b);
plot(app.UIAxes_2,z);

Y_avg = Y_sum / app.n_pow; 
St = sum ((Y - Y_avg).^2);
e = Y - ans(1) - ans(2) .*X;
Sr = sum(e.^2);
%Coefficient of determination
rsqr = (St - Sr)/St;
% correlation coefficient
r = rsqr .^0.5;

%print l hagat dy 


app.StEditField_2.Value = St;
app.SrEditField_2.Value = Sr;
app.r2EditField_2.Value = rsqr;
app.rEditField_2.Value = r;
app.labelLabel_2.Text = ans(1)  +"+"+ ans(2) + "* x";
       
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
           n = app.noofiterationsEditField.Value; 
            coeff = [];
res = [];
init = [app.X10EditField.Value app.X20EditField.Value app.X30EditField.Value];
           coeff = app.UITable_3.Data(:,[1 2 3]);


           res = app.UITable_3.Data(:,4);
           res = res';
       display(coeff)
       
if abs(diag(coeff)) > sum(abs(coeff),2) - abs(diag(coeff))
    flag = 1;
else
    flag = 0;
end

if flag == 1
    for i=1:n
       ans1 = ( (res(1)- (coeff(1,2).*init(2)) - (coeff(1,3).*init(3)) ) ./ coeff(1,1) );
       ans2 = ( (res(2)- (coeff(2,1).*init(1))- (coeff(2,3).*init(3)) )  ./ coeff(2,2) );
       ans3 = ( (res(3)- (coeff(3,1).*init(1))- (coeff(3,2).*init(2)) )  ./ coeff(3,3) );
       
       init(1) = ans1;
       init(2) = ans2;
       init(3) = ans3;
    end
    app.X1EditField.Value = ans1;
    app.X2EditField.Value = ans2;
    app.X3EditField.Value = ans3;
else
    warndlg("The matrix you have entered is not strict diagonally dominant")
end


        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
           var = string()
            y_s = app.UITable_3.Data(:,1);
            value = app.UITable_3.Data(:,2);
            
            str = app.EquationEditField.Value;
step_size = app.StepSizeEditField.Value;
xinitial =app.X0EditField.Value ;
yinitial = app.Y0EditField.Value;
a= xinitial;
b =app.pointofoperationatxEditField.Value;
n = (b-a)./step_size;
x = zeros(n+1,1);
y = zeros(n+1,1);
x(1)= xinitial;
y(1)= yinitial;
y_predictor= zeros(n+1, 1);
z = zeros(n+1,1);
f = inline(str,'x','y');
for i= 1 : n
    z(i) = feval(f,x(i),y(i));
    y_predictor(i) = y(i) + step_size .* z(i);
    g = (x(i) + step_size);
   y(i+1) = y(i) + (step_size./2) .* ( z(i) + feval ( f, g, y_predictor(i)) );
   x(i+1) = x(i) + step_size;
%    app.UITable2.Data(i,2) = y(i+1);
 
  var(i) = strcat('y(',int2str(i+1),')',' = ',int2str(y(i+1)));
  

    app.UITable2.Data = var';
end


% z = dy/dx

%disp(['"' str '", for x = ' num2str(x) ', for y = ' num2str(y) ', equals ' num2str(z)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LinearregressionTab = uitab(app.TabGroup);
            app.LinearregressionTab.Title = 'Linear regression';
            app.NumberofPointsEditFieldLabel = uilabel(app.LinearregressionTab);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.LinearregressionTab, 'numeric');
            app.NumberofPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsEditFieldValueChanged, true);
            app.UITable = uitable(app.LinearregressionTab);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CalculateButton = uibutton(app.LinearregressionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UIAxes = uiaxes(app.LinearregressionTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.LinearregressionTab);
            app.Label.Text = '';
            app.yLabel = uilabel(app.LinearregressionTab);
            app.yLabel.Text = 'y = ';
            app.labelLabel = uilabel(app.LinearregressionTab);
            app.labelLabel.Text = 'label';
            app.SrEditFieldLabel = uilabel(app.LinearregressionTab);
            app.SrEditFieldLabel.Text = 'Sr';
            app.SrEditField = uieditfield(app.LinearregressionTab, 'numeric');
            app.SrEditField.Enable = 'off';
            app.StEditFieldLabel = uilabel(app.LinearregressionTab);
            app.StEditFieldLabel.Text = 'St';
            app.StEditField = uieditfield(app.LinearregressionTab, 'numeric');
            app.StEditField.Enable = 'off';
            app.rEditFieldLabel = uilabel(app.LinearregressionTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.LinearregressionTab, 'numeric');
            app.rEditField.Enable = 'off';
            app.r2EditFieldLabel = uilabel(app.LinearregressionTab);
            app.r2EditFieldLabel.Text = 'r^2';
            app.r2EditField = uieditfield(app.LinearregressionTab, 'numeric');
            app.r2EditField.Enable = 'off';
            app.powermodelTab = uitab(app.TabGroup);
            app.powermodelTab.Title = 'power model';
            app.UITable_2 = uitable(app.powermodelTab);
            app.UITable_2.ColumnName = {'x'; 'y'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.NumberofPointsEditField_2Label = uilabel(app.powermodelTab);
            app.NumberofPointsEditField_2Label.Text = 'Number of Points';
            app.NumberofPointsEditField_2 = uieditfield(app.powermodelTab, 'numeric');
            app.NumberofPointsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsEditField_2ValueChanged, true);
            app.CalculateButton_2 = uibutton(app.powermodelTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.UIAxes_2 = uiaxes(app.powermodelTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.yLabel_2 = uilabel(app.powermodelTab);
            app.yLabel_2.Text = 'y = ';
            app.labelLabel_2 = uilabel(app.powermodelTab);
            app.labelLabel_2.Text = 'label';
            app.SrEditField_2Label = uilabel(app.powermodelTab);
            app.SrEditField_2Label.Text = 'Sr';
            app.SrEditField_2 = uieditfield(app.powermodelTab, 'numeric');
            app.SrEditField_2.Enable = 'off';
            app.StEditField_2Label = uilabel(app.powermodelTab);
            app.StEditField_2Label.Text = 'St';
            app.StEditField_2 = uieditfield(app.powermodelTab, 'numeric');
            app.StEditField_2.Enable = 'off';
            app.rEditField_2Label = uilabel(app.powermodelTab);
            app.rEditField_2Label.Text = 'r';
            app.rEditField_2 = uieditfield(app.powermodelTab, 'numeric');
            app.rEditField_2.Enable = 'off';
            app.r2EditField_2Label = uilabel(app.powermodelTab);
            app.r2EditField_2Label.Text = 'r^2';
            app.r2EditField_2 = uieditfield(app.powermodelTab, 'numeric');
            app.r2EditField_2.Enable = 'off';
            app.jacobiTab = uitab(app.TabGroup);
            app.jacobiTab.Title = 'jacobi';
            app.X10EditFieldLabel = uilabel(app.jacobiTab);
            app.X10EditFieldLabel.Text = 'X1(0)';
            app.X10EditField = uieditfield(app.jacobiTab, 'numeric');
            app.X20EditFieldLabel = uilabel(app.jacobiTab);
            app.X20EditFieldLabel.Text = 'X2(0)';
            app.X20EditField = uieditfield(app.jacobiTab, 'numeric');
            app.X30EditFieldLabel = uilabel(app.jacobiTab);
            app.X30EditFieldLabel.Text = 'X3(0)';
            app.X30EditField = uieditfield(app.jacobiTab, 'numeric');
            app.UITable_3 = uitable(app.jacobiTab);
            app.UITable_3.ColumnName = {'a1'; 'a2'; 'a3'; 'b'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.CalculateButton_3 = uibutton(app.jacobiTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.EntertheequationscoeffandtheirresultLabel = uilabel(app.jacobiTab);
            app.EntertheequationscoeffandtheirresultLabel.Text = 'Enter the equations coeff. and their result';
            app.X1EditFieldLabel = uilabel(app.jacobiTab);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.jacobiTab, 'numeric');
            app.X1EditField.Enable = 'off';
            app.X2EditFieldLabel = uilabel(app.jacobiTab);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.jacobiTab, 'numeric');
            app.X2EditField.Enable = 'off';
            app.X3EditFieldLabel = uilabel(app.jacobiTab);
            app.X3EditFieldLabel.Text = 'X3';
            app.X3EditField = uieditfield(app.jacobiTab, 'numeric');
            app.X3EditField.Enable = 'off';
            app.noofiterationsLabel = uilabel(app.jacobiTab);
            app.noofiterationsLabel.Text = 'no. of iterations';
            app.noofiterationsEditField = uieditfield(app.jacobiTab, 'numeric');
            app.noofiterationsEditField.Value = 3;
            app.HeunsTab = uitab(app.TabGroup);
            app.HeunsTab.Title = 'Heuns';
            app.EquationEditFieldLabel = uilabel(app.HeunsTab);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.HeunsTab, 'text');
            app.EquationEditField.Value = '3*x+2';
            app.StepSizeEditFieldLabel = uilabel(app.HeunsTab);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.HeunsTab, 'numeric');
            app.StepSizeEditField.Value = 1;
            app.X0EditFieldLabel = uilabel(app.HeunsTab);
            app.X0EditFieldLabel.Text = 'X(0)';
            app.X0EditField = uieditfield(app.HeunsTab, 'numeric');
            app.Y0EditFieldLabel = uilabel(app.HeunsTab);
            app.Y0EditFieldLabel.Text = 'Y(0)';
            app.Y0EditField = uieditfield(app.HeunsTab, 'numeric');
            app.pointofoperationatxEditFieldLabel = uilabel(app.HeunsTab);
            app.pointofoperationatxEditFieldLabel.Text = 'point of operation at x';
            app.pointofoperationatxEditField = uieditfield(app.HeunsTab, 'numeric');
            app.pointofoperationatxEditField.Value = 2;
            app.CalculateButton_4 = uibutton(app.HeunsTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.UITable2 = uitable(app.HeunsTab);
            app.UITable2.ColumnName = {'value'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appmath

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end