classdef apptask1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConductorResistivityEditFieldLabel  matlab.ui.control.Label
        ConductorResistivityEditField   matlab.ui.control.NumericEditField
        ConductorLengthkmEditFieldLabel  matlab.ui.control.Label
        ConductorLengthkmEditField      matlab.ui.control.NumericEditField
        ConductorDiametercmEditFieldLabel  matlab.ui.control.Label
        ConductorDiametercmEditField    matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        SpacingSystemButtonGroup        matlab.ui.container.ButtonGroup
        SymmetricalButton               matlab.ui.control.RadioButton
        UnsymmetricalButton             matlab.ui.control.RadioButton
        DEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        D1EditFieldLabel                matlab.ui.control.Label
        D1EditField                     matlab.ui.control.NumericEditField
        D2EditFieldLabel                matlab.ui.control.Label
        D2EditField                     matlab.ui.control.NumericEditField
        D3EditFieldLabel                matlab.ui.control.Label
        D3EditField                     matlab.ui.control.NumericEditField
        DCResistanceRdcEditFieldLabel   matlab.ui.control.Label
        DCResistanceRdcEditField        matlab.ui.control.NumericEditField
        ACResistanceRacLabel            matlab.ui.control.Label
        ACResistanceRacEditField        matlab.ui.control.NumericEditField
        InductanceperphaseLphEditFieldLabel  matlab.ui.control.Label
        InductanceperphaseLphEditField  matlab.ui.control.NumericEditField
        CapacitanceperphaseCphEditFieldLabel  matlab.ui.control.Label
        CapacitanceperphaseCphEditField  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        D_total; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            row = app.ConductorResistivityEditField.Value
           
            length = app.ConductorLengthkmEditField.Value
            
            diameter = app.ConductorDiametercmEditField.Value
            
            D  = app.DEditField.Value
            D1 = app.D1EditField.Value
            D2 = app.D2EditField.Value
            D3 = app.D3EditField.Value
            D_tot = (D1*D2*D3)^(1/3);
            
            if (app.SymmetricalButton.Value == 1)
                    app.D_total = D;
            end
          if (app.UnsymmetricalButton.Value == 1)
                    app.D_total = D_tot;       
          end
          
          
            disp('D')
            disp(app.SymmetricalButton.Value)
            disp(D)
            disp('app.D_total')
            disp(app.D_total)
            
            %calculating resistance
            r = diameter/(2*100);
            Rdc = (row * length *1000)/ ( pi * (r^2));
            
            app.DCResistanceRdcEditField.Value = Rdc;
            
            Rac = 1.1 * Rdc;
            app.ACResistanceRacEditField.Value = Rac;
            
            %calculating inductance 
            GMR = r * exp(-0.25);
            Lph_per_m = 2*10^(-7) * log(app.D_total/GMR);
            Lph = Lph_per_m * length *1000;
            disp('debug')
            disp(app.D_total)
            disp('GMR')
            disp(GMR)
            app.InductanceperphaseLphEditField.Value = Lph;
            %Lph = num2str(Lph);
            Xlph = 2*pi*50*Lph;
 
            %calculating capacitance
            Cph_per_m = (2* pi *8.854 * 10^(-12))/(log(app.D_total/r));
            Cph = Cph_per_m *length*1000;
            app.CapacitanceperphaseCphEditField.Value = Cph;
            Xcph = 1./(2*pi*50*Cph);
            
        end

        % Selection changed function: SpacingSystemButtonGroup
        function SpacingSystemButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpacingSystemButtonGroup.SelectedObject;
            switch selectedButton
                case app.SymmetricalButton
                    app.DEditField.Enable = 'On'
                    app.D1EditField.Enable = 'Off'
                    app.D2EditField.Enable = 'Off'
                    app.D3EditField.Enable = 'Off'
                case app.UnsymmetricalButton
                    app.DEditField.Enable = 'Off'
                    app.D1EditField.Enable = 'On'
                    app.D2EditField.Enable = 'On'
                    app.D3EditField.Enable = 'On'
            end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConductorResistivityEditFieldLabel = uilabel(app.UIFigure);
            app.ConductorResistivityEditFieldLabel.Text = 'Conductor Resistivity';
            app.ConductorResistivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductorResistivityEditField.Tag = 'row';
            app.ConductorLengthkmEditFieldLabel = uilabel(app.UIFigure);
            app.ConductorLengthkmEditFieldLabel.Text = 'Conductor Length (km)';
            app.ConductorLengthkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductorLengthkmEditField.Tag = 'length';
            app.ConductorDiametercmEditFieldLabel = uilabel(app.UIFigure);
            app.ConductorDiametercmEditFieldLabel.Text = 'Conductor Diameter (cm)';
            app.ConductorDiametercmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductorDiametercmEditField.Tag = 'diameter';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SpacingSystemButtonGroup = uibuttongroup(app.UIFigure);
            app.SpacingSystemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpacingSystemButtonGroupSelectionChanged, true);
            app.SpacingSystemButtonGroup.Title = 'Spacing System';
            app.SymmetricalButton = uiradiobutton(app.SpacingSystemButtonGroup);
            app.SymmetricalButton.Tag = 'symm';
            app.SymmetricalButton.Text = 'Symmetrical';
            app.SymmetricalButton.Value = true;
            app.UnsymmetricalButton = uiradiobutton(app.SpacingSystemButtonGroup);
            app.UnsymmetricalButton.Tag = 'unsymm';
            app.UnsymmetricalButton.Text = 'Unsymmetrical';
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.Tag = 'D';
            app.D1EditFieldLabel = uilabel(app.UIFigure);
            app.D1EditFieldLabel.Tag = 'd1';
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.UIFigure, 'numeric');
            app.D1EditField.Tag = 'd1';
            app.D2EditFieldLabel = uilabel(app.UIFigure);
            app.D2EditFieldLabel.Tag = 'd2';
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.UIFigure, 'numeric');
            app.D2EditField.Tag = 'd2';
            app.D3EditFieldLabel = uilabel(app.UIFigure);
            app.D3EditFieldLabel.Tag = 'd3';
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.UIFigure, 'numeric');
            app.D3EditField.Tag = 'd3';
            app.DCResistanceRdcEditFieldLabel = uilabel(app.UIFigure);
            app.DCResistanceRdcEditFieldLabel.Text = 'DC Resistance (Rdc)';
            app.DCResistanceRdcEditField = uieditfield(app.UIFigure, 'numeric');
            app.DCResistanceRdcEditField.Tag = 'rdc';
            app.ACResistanceRacLabel = uilabel(app.UIFigure);
            app.ACResistanceRacLabel.Text = 'AC Resistance (Rac)';
            app.ACResistanceRacEditField = uieditfield(app.UIFigure, 'numeric');
            app.ACResistanceRacEditField.Tag = 'rac';
            app.InductanceperphaseLphEditFieldLabel = uilabel(app.UIFigure);
            app.InductanceperphaseLphEditFieldLabel.Text = 'Inductance per phase (Lph)';
            app.InductanceperphaseLphEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductanceperphaseLphEditField.Tag = 'lph';
            app.CapacitanceperphaseCphEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceperphaseCphEditFieldLabel.Text = 'Capacitance per phase (Cph)';
            app.CapacitanceperphaseCphEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanceperphaseCphEditField.Tag = 'cph';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptask1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end