classdef app1edit2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        SamplingdataPanel              matlab.ui.container.Panel
        SamplingfrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyEditField     matlab.ui.control.NumericEditField
        SamplingintervalLabel          matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        noofbreakpointsEditFieldLabel  matlab.ui.control.Label
        noofbreakpointsEditField       matlab.ui.control.NumericEditField
        TabGroup                       matlab.ui.container.TabGroup
        Signal1Tab                     matlab.ui.container.Tab
        PositionEditFieldLabel         matlab.ui.control.Label
        PositionEditField              matlab.ui.control.NumericEditField
        Signal1typeDropDownLabel       matlab.ui.control.Label
        Signal1typeDropDown            matlab.ui.control.DropDown
        PlotButton                     matlab.ui.control.Button
        PlotButton_2                   matlab.ui.control.Button
        OperationDropDownLabel         matlab.ui.control.Label
        OperationDropDown              matlab.ui.control.DropDown
        valueLabel                     matlab.ui.control.Label
        valueEditField                 matlab.ui.control.NumericEditField
        MadewithLoveLabel              matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        xsignal % Description
        ttime % Description
        start_time % Description
        time_pt % Description
        ef % Description
        bp_times % Description
        frequency % Description
        in_func % Description
        func_list % Description
        nobp % Description
        numfld % Description
        ddfld % Description
        x % Description
        t % Description
        end_time % Description
    end


    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             app.noofbreakpointsEditField.Enable = "off";
            app.frequency = app.SamplingfrequencyEditField.Value;
            app.start_time = app.EditField.Value;
            app.end_time = app.EditField_2.Value;
            samples =( app.end_time - app.start_time ) * app.frequency;                
            app.in_func = strings(); 
                            app.bp_times = [];
                for i = 1:app.nobp
                   
                    current_tab = app.TabGroup.Children(i+1);
                    childs = allchild(current_tab);
                  app.bp_times(i) =   childs(3).Value;
                  if app.bp_times(i) ~= round(app.bp_times(i))
                    app.bp_times(i) = ceil(app.bp_times(i));
                  else
                      app.bp_times(i) = app.bp_times(i);
                  end

                    app.in_func(i) = childs(1).Value;
                end 
            app.time_pt = [app.start_time app.bp_times  app.end_time];
  
if app.nobp > 0
     f_sig_arr= string(); 
    for i = 1:app.nobp-1
     f_sig_arr(end) = "";
    end
          f_sig_arr(1) = app.Signal1typeDropDown.Value; 


    else 
            f_sig_arr = string();
            f_sig_arr(1) = app.Signal1typeDropDown.Value; 
            app.in_func= string(); 

            
end

           app.func_list = [f_sig_arr   app.in_func];


lin_times = cell(1,length(app.time_pt)-1) ;                                                           
func_eq = cell(1,length(app.time_pt)-1) ;                                                       

for j = 1 : length(app.time_pt)-1

    t_start = app.time_pt(j);
    t_end =app.time_pt(j+1);

    lin_times{j} = linspace(t_start , t_end,( ( t_end - t_start ) * app.frequency) + 1); 

            if app.func_list{j} == "DC signal"
                prompt = {'Enter amplitude'};
                dlgtitle = 'Amplitude Value';
                definput = {'3'};
                dims = [1 40];
                opts.Interpreter = 'tex';
                answer = inputdlg(prompt,dlgtitle,dims,definput,opts);
                func_eq{j} = str2num(answer{1}) * ones(1,length(lin_times{j}));
                               
            elseif app.func_list{j} == "Ramp signal"
                prompt = {'Slope:','Intercept:'};
                dlgtitle = 'Input';
                dims = [1 40];
                definput = {'2','3'};
                answer = inputdlg(prompt,dlgtitle,dims,definput)
                func_eq{j}= (str2num(answer{1}) * lin_times{j}) + str2num(answer{2});
            elseif app.func_list{j} == "General order polynomial"
                prompt = {'Amplitude:','Power:','Intercept:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'3','2','5'};
                answer = inputdlg(prompt,dlgtitle,dims,definput)
                sig_amp = str2num(answer{1});
                sig_pow = str2num(answer{2});
                sig_int = str2num(answer{3});

           func_eq{j} = zeros(1, length(app.ttime));
            for k = sig_pow:-1:1
                func_eq{j} =  func_eq{j} + sig_amp * lin_times{j} .^ k ;
            end
             func_eq{j} = func_eq{j} + sig_int;
            
            
            elseif  app.func_list{j} == "Exponential signal"
                prompt = {'Amplitude:','Exponent:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'4','0.5'};
                answer = inputdlg(prompt,dlgtitle,dims,definput)
                sig_amp = str2num(answer{1});
                sig_exp = str2num(answer{2});
                func_eq{j}= sig_amp * exp(sig_exp * lin_times{j});
                
             elseif  app.func_list{j} == "Sinusoidal signal"
                prompt = {'Amplitude:','Frequency:','Phase'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'4','500', '2'};
                answer = inputdlg(prompt,dlgtitle,dims,definput)
                sig_amp = str2num(answer{1});
                sig_freq = str2num(answer{2});
                sig_phase = str2num(answer{3});
  
               func_eq{j} = sig_amp * sin((2*pi*sig_freq*lin_times{j})+(sig_phase));

            end


end


 app.t = [ ];
for j = 1 : length(lin_times)
    app.t = [ app.t lin_times{j}(1:end-1) ];
end
 app.t = [ app.t lin_times{end}(end) ];
 disp(lin_times{end}(end))

app.x = [ ];
for k = 1 : length(func_eq)
    app.x = [ app.x func_eq{k}(1:end-1) ];
end
app.x = [ app.x func_eq{end}(end)];
disp(func_eq{end}(end))


plot(app.UIAxes,app.t,app.x);


        end

        % Value changed function: noofbreakpointsEditField
        function noofbreakpointsEditFieldValueChanged(app, event)
            app.nobp = app.noofbreakpointsEditField.Value;
            app.noofbreakpointsEditField.Enable = "off";
            if app.nobp > 0

                for i = 1:app.nobp
                tab = uitab(app.TabGroup,'Title',['Signal #' num2str(i+1)]); 
                lb = uilabel(tab,"Text","Position","Position",[8 52 216 22]);
                app.numfld = uieditfield(tab,'numeric','Position',[120 52 100 22],'Value',0,'HorizontalAlignment','left');
                
                lb = uilabel(tab,"Text",['Signal #' num2str(i+1) ' type'],"Position",[8 22 82 22]);
                app.numfld = uidropdown(tab,'Position',[120 22 100 22],'Items',{'DC signal', 'Ramp signal', 'General order polynomial', 'Exponential signal', ...
                    'Sinusoidal signal'});

                
                end 
                    y =  (app.nobp < 0) | (round(app.nobp) ~= app.nobp)  | (app.nobp < (1/3 * (app.frequency * (app.end_time - app.start_time) +1) -1)); 
                    if y ==1
   warndlg('Please enter a valid breaking point','Warning');
                    else 
                        app.nobp = app.nobp;

                    end
                    
               
            end


        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;
            if value == "Time reversal" || value == "None"
                app.valueEditField.Enable = "off";
            else
                app.valueEditField.Enable = "on";
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            value = app.OperationDropDown.Value;
            val = app.valueEditField.Value;
            temp_signal = app.x;
            temp_time = app.t;
            if value == "Expanding the signal"
                app.valueEditField.Limits = [0 1];
                temp_time = temp_time/val;
                plot(app.UIAxes2,temp_time,temp_signal);
            elseif value == "None"
                plot(app.UIAxes2,temp_time,temp_signal);
            elseif value == "Compressing the signal"
                app.valueEditField.Limits = [1 Inf];
                temp_time = temp_time/val;
                plot(app.UIAxes2,temp_time,temp_signal);
            elseif value == "Amplitude scaling"
                temp_signal = val * temp_signal;
                plot(app.UIAxes2,temp_time,temp_signal);
            elseif value== "Time reversal"
                temp_time = -(temp_time);
                plot(app.UIAxes2,temp_time,temp_signal); 
            elseif value == "Time shift"
                temp_time = (temp_time)+val;
                plot(app.UIAxes2,temp_time,temp_signal); 
            end
           
        end

        % Value changed function: SamplingfrequencyEditField
        function SamplingfrequencyEditFieldValueChanged(app, event)
            value = app.SamplingfrequencyEditField.Value;
            
        end

        % Value changed function: EditField, Signal1typeDropDown
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Callback function
        function ButtonPushed(app, event)
           
       
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SamplingdataPanel = uipanel(app.GridLayout2);
            app.SamplingdataPanel.Title = 'Sampling data';
            app.SamplingfrequencyEditFieldLabel = uilabel(app.SamplingdataPanel);
            app.SamplingfrequencyEditFieldLabel.Text = 'Sampling frequency';
            app.SamplingfrequencyEditField = uieditfield(app.SamplingdataPanel, 'numeric');
            app.SamplingfrequencyEditField.Limits = [1 Inf];
            app.SamplingfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingfrequencyEditFieldValueChanged, true);
            app.SamplingfrequencyEditField.Tag = 'freq_edit';
            app.SamplingfrequencyEditField.Value = 1000;
            app.SamplingintervalLabel = uilabel(app.SamplingdataPanel);
            app.SamplingintervalLabel.Text = 'Sampling interval';
            app.EditField = uieditfield(app.SamplingdataPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = 't1_edit';
            app.EditField.Value = -3;
            app.Label = uilabel(app.SamplingdataPanel);
            app.Label.Text = ':';
            app.EditField_2 = uieditfield(app.SamplingdataPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tag = 't2_edit';
            app.EditField_2.Value = 3;
            app.noofbreakpointsEditFieldLabel = uilabel(app.SamplingdataPanel);
            app.noofbreakpointsEditFieldLabel.Text = 'no. of breakpoints';
            app.noofbreakpointsEditField = uieditfield(app.SamplingdataPanel, 'numeric');
            app.noofbreakpointsEditField.Limits = [0 Inf];
            app.noofbreakpointsEditField.ValueChangedFcn = createCallbackFcn(app, @noofbreakpointsEditFieldValueChanged, true);
            app.noofbreakpointsEditField.Tag = 'no_bp';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.Tooltip = {''};
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Signal1Tab = uitab(app.TabGroup);
            app.Signal1Tab.Title = 'Signal #1';
            app.PositionEditFieldLabel = uilabel(app.Signal1Tab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.Signal1Tab, 'numeric');
            app.PositionEditField.Tag = 'pos1_edit';
            app.PositionEditField.Enable = 'off';
            app.Signal1typeDropDownLabel = uilabel(app.Signal1Tab);
            app.Signal1typeDropDownLabel.Text = 'Signal #1 type';
            app.Signal1typeDropDown = uidropdown(app.Signal1Tab);
            app.Signal1typeDropDown.Items = {'DC signal', 'Ramp signal', 'General order polynomial', 'Exponential signal', 'Sinusoidal signal'};
            app.Signal1typeDropDown.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Signal1typeDropDown.Tag = 'sigty_edit';
            app.Signal1typeDropDown.Value = 'DC signal';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'graph_edit';
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.GridLayout2, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.OperationDropDownLabel = uilabel(app.GridLayout2);
            app.OperationDropDownLabel.Text = 'Operation:';
            app.OperationDropDown = uidropdown(app.GridLayout2);
            app.OperationDropDown.Items = {'Amplitude scaling', 'Time reversal', 'Time shift', 'Expanding the signal', 'Compressing the signal', 'None'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = 'Amplitude scaling';
            app.valueLabel = uilabel(app.GridLayout2);
            app.valueLabel.Text = 'value:';
            app.valueEditField = uieditfield(app.GridLayout2, 'numeric');
            app.valueEditField.Value = 1;
            app.MadewithLoveLabel = uilabel(app.GridLayout2);
            app.MadewithLoveLabel.Text = 'Made with Love 🧡';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Generated Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Signal after operation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1edit2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end