classdef final_signals_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TransmitterLabel      matlab.ui.control.Label
        TransmitterEditField  matlab.ui.control.EditField
        PlayPlotButton_4      matlab.ui.control.Button
        BrowseButton          matlab.ui.control.Button
        ChannelLabel          matlab.ui.control.Label
        ChannelDropDown       matlab.ui.control.DropDown
        PlayPlotButton_3      matlab.ui.control.Button
        SigmanoiseLabel       matlab.ui.control.Label
        SigmanoiseEditField   matlab.ui.control.NumericEditField
        PlayPlotButton_2      matlab.ui.control.Button
        ReceiverLabel         matlab.ui.control.Label
        ReceiverTextArea      matlab.ui.control.TextArea
        PlayPlotButton        matlab.ui.control.Button
        SpeakerButtonGroup    matlab.ui.container.ButtonGroup
        LeftButton            matlab.ui.control.RadioButton
        RightButton           matlab.ui.control.RadioButton
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        LeftsideSpeakerLabel  matlab.ui.control.Label
        MadewithloveLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        signal_t % Description
        fs % Description
        signal_f % Description
        fvec % Description
        t % Description
        speaker % Description
        conv_out_t % Description
        conv_out_f % Description
        noise_out_t % Description
        noise_out_f % Description
        conv_fvec % Description
        conv_t % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
   [file,path] = uigetfile({'*.wav';'*.mp3'});
if isequal(file,0)
   warndlg('Please choose a correct file');
else
    input_file = fullfile(path,file);
   app.TransmitterEditField.Value = input_file;
   [app.signal_t, app.fs] = audioread(input_file);
app.signal_f= fftshift(fft(app.signal_t));

app.fvec = linspace(-app.fs/2,app.fs/2,length(app.signal_t));

app.t = linspace(0, (1/app.fs)*length(app.signal_t), length(app.signal_t));

end

app.speaker = 1;
 app.RightButton.Enable ="on";
        end

        % Button pushed function: PlayPlotButton_4
        function PlayPlotButton_4Pushed(app, event)

              if app.PlayPlotButton_4.Text == "Play/Plot"
   plot(app.UIAxes,app.t,app.signal_t(:,app.speaker));
              plot(app.UIAxes2,app.fvec,app.signal_f(:,app.speaker));
%             figure;  plot(app.t,app.signal_t(:,app.speaker));

sound(app.signal_t, app.fs);
              app.PlayPlotButton_4.Text = "Stop";
              else

clear sound;
                app.PlayPlotButton_4.Text = "Play/Plot"  ;
                
              end
        end

        % Button pushed function: PlayPlotButton_3
        function PlayPlotButton_3Pushed(app, event)
            channel = app.ChannelDropDown.Value;
            if  channel== "𝛿(t)"

                h_t = [1 zeros(1, length(app.signal_t)-1)]';

            elseif channel== "exp(-2pi*5000t)"
                 h_t = exp(-2*pi*5000*app.t)';
            elseif channel== "exp(-2pi*1000t)"
                 h_t = exp(-2*pi*1000*app.t)';
            elseif channel== "2𝛿(t)+0.5𝛿(t-1)"    
                 h_t = [2 0.5 zeros(1, length(app.signal_t)-2)]';
                 
                
            end
            app.conv_out_t = conv(app.signal_t(:,app.speaker),h_t);
            app.conv_out_f = fftshift(fft(app.conv_out_t));
            app.conv_t = linspace(0, (1/app.fs)*length(app.conv_out_t), length(app.conv_out_t));
            app.conv_fvec = linspace(-app.fs/2,app.fs/2,length(app.conv_out_t));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%play and plot
            if app.PlayPlotButton_3.Text == "Play/Plot"
                sound(app.conv_out_t, app.fs);
   plot(app.UIAxes,app.conv_t,app.conv_out_t);
              plot(app.UIAxes2,app.conv_fvec,app.conv_out_f);
              
%           figure;   plot(app.t,app.conv_out_t);

    
              app.PlayPlotButton_3.Text = "Stop";
              else

clear sound;
                app.PlayPlotButton_3.Text = "Play/Plot"  ;
                
              end


        end

        % Button pushed function: PlayPlotButton_2
        function PlayPlotButton_2Pushed(app, event)

            
            sigma = app.SigmanoiseEditField.Value;
            noise = sigma * randn(length(app.conv_out_t),1);
            noise = abs(fftshift(fft(noise)));
            app.noise_out_f = app.conv_out_f+noise;

             app.noise_out_t = ifft(ifftshift(app.noise_out_f));
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%play and plot
            if app.PlayPlotButton_2.Text == "Play/Plot"
                sound(app.noise_out_t, app.fs);
   plot(app.UIAxes,app.conv_t,app.noise_out_t);
               plot(app.UIAxes2,app.conv_fvec,app.noise_out_f);

              app.PlayPlotButton_2.Text = "Stop";
              else

clear sound;
                app.PlayPlotButton_2.Text = "Play/Plot"  ;
                
            end
            
        end

        % Button pushed function: PlayPlotButton
        function PlayPlotButtonPushed(app, event)
filter_out_f = app.noise_out_f;
samp_per_hz = length(filter_out_f)/app.fs;
zero_samples = round(samp_per_hz * ((app.fs/2)-(3.4*1000)));
filter_out_f([1:zero_samples]) = 0;
filter_out_f([(end-zero_samples+1):end]) = 0;
filter_out_t = real(ifft(ifftshift(filter_out_f)));
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%play and plot
            if app.PlayPlotButton.Text == "Play/Plot"
                   sound(filter_out_t, app.fs);
                   plot(app.UIAxes,app.conv_t,filter_out_t);
                    plot(app.UIAxes2,app.conv_fvec,filter_out_f);
                
                    
                              app.PlayPlotButton.Text = "Stop";
              else
                                
                                clear sound;
                                app.PlayPlotButton.Text = "Play/Plot"  ;
                
              end

        end

        % Selection changed function: SpeakerButtonGroup
        function SpeakerButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpeakerButtonGroup.SelectedObject;
            
            [rownum,colnum]=size(app.signal_t);
           if colnum <=1
                warndlg('Sound is not stereo');
                app.RightButton.Enable ="off";
               
                app.SpeakerButtonGroup.SelectedObject =app.LeftButton;
           elseif colnum == 2
                          if selectedButton == app.RightButton
            app.speaker =2;
            app.LeftsideSpeakerLabel.Text = "Right-side Speaker";
                          elseif selectedButton ==app.LeftButton
               app.speaker = 1;
               app.LeftsideSpeakerLabel.Text = "Left-side Speaker";

           end
           end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {532, 532};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {382, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TransmitterLabel = uilabel(app.LeftPanel);
            app.TransmitterLabel.Text = 'Transmitter:';
            app.TransmitterEditField = uieditfield(app.LeftPanel, 'text');
            app.TransmitterEditField.Editable = 'off';
            app.PlayPlotButton_4 = uibutton(app.LeftPanel, 'push');
            app.PlayPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayPlotButton_4Pushed, true);
            app.PlayPlotButton_4.Text = 'Play/Plot';
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChannelLabel = uilabel(app.LeftPanel);
            app.ChannelLabel.Text = 'Channel:';
            app.ChannelDropDown = uidropdown(app.LeftPanel);
            app.ChannelDropDown.Items = {'𝛿(t)', 'exp(-2pi*5000t)', 'exp(-2pi*1000t)', '2𝛿(t)+0.5𝛿(t-1)'};
            app.ChannelDropDown.Value = '𝛿(t)';
            app.PlayPlotButton_3 = uibutton(app.LeftPanel, 'push');
            app.PlayPlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayPlotButton_3Pushed, true);
            app.PlayPlotButton_3.Text = 'Play/Plot';
            app.SigmanoiseLabel = uilabel(app.LeftPanel);
            app.SigmanoiseLabel.Text = 'Sigma(noise):';
            app.SigmanoiseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SigmanoiseEditField.Value = 1;
            app.PlayPlotButton_2 = uibutton(app.LeftPanel, 'push');
            app.PlayPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayPlotButton_2Pushed, true);
            app.PlayPlotButton_2.Text = 'Play/Plot';
            app.ReceiverLabel = uilabel(app.LeftPanel);
            app.ReceiverLabel.Text = 'Receiver:';
            app.ReceiverTextArea = uitextarea(app.LeftPanel);
            app.ReceiverTextArea.Editable = 'off';
            app.ReceiverTextArea.Value = {'Apply an ideal low pass filter which has a cut off of 3.4 KHz'};
            app.PlayPlotButton = uibutton(app.LeftPanel, 'push');
            app.PlayPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPlotButtonPushed, true);
            app.PlayPlotButton.Text = 'Play/Plot';
            app.SpeakerButtonGroup = uibuttongroup(app.LeftPanel);
            app.SpeakerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeakerButtonGroupSelectionChanged, true);
            app.SpeakerButtonGroup.Title = 'Speaker';
            app.LeftButton = uiradiobutton(app.SpeakerButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SpeakerButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Time domain')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Frequency domain')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.LeftsideSpeakerLabel = uilabel(app.RightPanel);
            app.LeftsideSpeakerLabel.Text = 'Left-side Speaker';
            app.MadewithloveLabel = uilabel(app.RightPanel);
            app.MadewithloveLabel.Text = 'Made with love 🧡';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_signals_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end